classdef Elevator_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image_Door_Close  matlab.ui.control.Image
        Button_1          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_Open       matlab.ui.control.Button
        Button_Close      matlab.ui.control.Button
        FloorPanel        matlab.ui.container.Panel
        Label_Floor       matlab.ui.control.Label
        Label_Direction   matlab.ui.control.Label
        Lamp_2            matlab.ui.control.Lamp
        Lamp_3            matlab.ui.control.Lamp
        Lamp_Open         matlab.ui.control.Lamp
        Lamp_Close        matlab.ui.control.Lamp
        Lamp_1            matlab.ui.control.Lamp
        Image_Door_Open   matlab.ui.control.Image
    end

    
    properties (Access = public)
        elevatorProcessor 
        NO
    end
    
    methods (Access = public)
        
        function displayFloorMessage(app, FloorMessage)
            app.Label_Floor.Text = FloorMessage;
        end
        
        function displayDirectionMessage(app, DirectionMessage)
            app.Label_Direction.Text = DirectionMessage;
        end
        
        function openInsideDoor(app)
            pause(1);
            app.Image_Door_Open.Visible = 'On';
        end
        
        function closeInsideDoor(app)
            pause(1);
            app.Image_Door_Open.Visible = 'Off';
        end
        
        function closeLight(app,floor)
            switch floor
                case 1
                    app.Lamp_1.Color = [0.9,0.9,0.9];
                case 2
                    app.Lamp_2.Color = [0.9,0.9,0.9];
                case 3
                    app.Lamp_3.Color = [0.9,0.9,0.9];
            end
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image_Door_Close = uiimage(app.UIFigure);
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '3';
            app.Button_Open = uibutton(app.UIFigure, 'push');
            app.Button_Open.Text = '< | >';
            app.Button_Close = uibutton(app.UIFigure, 'push');
            app.Button_Close.Text = '> | <';
            app.FloorPanel = uipanel(app.UIFigure);
            app.FloorPanel.Title = 'Floor';
            app.Label_Floor = uilabel(app.FloorPanel);
            app.Label_Floor.Text = '1';
            app.Label_Direction = uilabel(app.FloorPanel);
            app.Label_Direction.Text = ' ';
            app.Lamp_1 = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_Open = uilamp(app.UIFigure);
            app.Lamp_Close = uilamp(app.UIFigure);
            app.Image_Door_Open = uiimage(app.UIFigure);
            app.Image_Door_Open.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Elevator_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end