classdef patient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Lamp      matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        physician
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            name = 'name1';
            switch name
                case 'name1'
                    left = app.physician.name1left;
                    bolus = app.physician.name1bolus;
                    usedday = app.physician.name1used1;
                    usedhr = app.physician.name1used2;
                case 'name2'
                    left = app.physician.name2left;
                    bolus = app.physician.name2bolus;
                    usedday = app.physician.name2used1;
                    usedhr = app.physician.name2used2;
                case 'name3'
                    left = app.physician.name3left;
                    bolus = app.physician.name1b3lus;
                    usedday = app.physician.name3sed1;
                    usedhr = app.physician.name3used2;
                case 'name4'
                    left = app.physician.name4left;
                    bolus = app.physician.name4bolus;
                    usedday = app.physician.name4used1;
                    usedhr = app.physician.name4used2;
            end
            if left<bolus
                app.Lamp.Color = [0.8,0.0,0.0];
            elseif usedday+bolus>3
                app.Lamp.Color = [0.8,0.0,0.0];
            elseif usedhr+bolus>1
                app.Lamp.Color = [0.8,0.0,0.0];
            else
                app.Lamp.Color = [0.0,0.8,0.0];
                left = left-bolus;
                switch name
                    case 'name1'
                        app.physician.name1left = left;
                    case 'name2'
                        app.physician.name2left = left;
                    case 'name3'
                        app.physician.name3left = left;
                    case 'name4'
                        app.physician.name4left = left;
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patient

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end