classdef physician < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        name1Menu                       matlab.ui.container.Menu
        name2Menu                       matlab.ui.container.Menu
        name3Menu                       matlab.ui.container.Menu
        name4Menu                       matlab.ui.container.Menu
        limit3Label                     matlab.ui.control.Label
        someotherinformationLabel       matlab.ui.control.Label
        usedmldayTextAreaLabel          matlab.ui.control.Label
        usedmldayTextArea               matlab.ui.control.TextArea
        limit1Label                     matlab.ui.control.Label
        usedmlhrTextAreaLabel           matlab.ui.control.Label
        usedmlhrTextArea                matlab.ui.control.TextArea
        mlminLabel                      matlab.ui.control.Label
        mlshotLabel                     matlab.ui.control.Label
        mlLabel                         matlab.ui.control.Label
        baselinesetting00101EditFieldLabel  matlab.ui.control.Label
        baselinesetting00101EditField   matlab.ui.control.NumericEditField
        addtoEditFieldLabel             matlab.ui.control.Label
        addtoEditField                  matlab.ui.control.NumericEditField
        changeButton                    matlab.ui.control.Button
        changeButton_2                  matlab.ui.control.Button
        baselinemlminLabel              matlab.ui.control.Label
        baselinemlminTextArea           matlab.ui.control.TextArea
        bolusmlshotTextAreaLabel        matlab.ui.control.Label
        bolusmlshotTextArea             matlab.ui.control.TextArea
        addButton                       matlab.ui.control.Button
        medicineleftTextAreaLabel       matlab.ui.control.Label
        medicineleftTextArea            matlab.ui.control.TextArea
        mlLabel_2                       matlab.ui.control.Label
        bolussetting0205EditFieldLabel  matlab.ui.control.Label
        bolussetting0205EditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        t = timer;
        name = 'name1';
        name1baseline = 0.01; name1bolus = 0.2; name1used1= 0.0; name1used2= 0.0;name1left= 20;
        name2baseline = 0.01; name2bolus = 0.2; name2used1= 0.0; name2used2= 0.0;name2left= 20;
        name3baseline = 0.01; name3bolus = 0.2; name3used1= 0.0; name3used2= 0.0;name3left= 20;
        name4baseline = 0.01; name4bolus = 0.2; name4used1= 0.0; name4used2= 0.0;name4left= 20;
    end
    
    properties (Access = private)
        patient
        Timer_id;
    end
    
    methods (Access = private)
        
        function timebalance = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function baseline00101TextAreaValueChanged(app, event)
            
        end

        % Callback function
        function bolussetting0205EditFieldValueChanged(app, event)
            
        end

        % Button pushed function: changeButton
        function changeButtonPushed(app, event)
            baseline = app.baselinemlminTextArea.Value;
            value = app.baselinesetting00101EditField.Value;
            if value >= 0.1
                app.baselinemlminTextArea.Value = baseline;
            elseif value <= 0.01
                app.baselinemlminTextArea.Value = baseline;
            end
            switch app.name
                case 'name1'
                    app.name1baseline = value;
                    app.baselinemlminTextArea.Value = num2str(value);
                case 'name2'
                    app.name2baseline = value;
                    app.baselinemlminTextArea.Value = num2str(value);
                case 'name3'
                    app.name3baseline = value;
                    app.baselinemlminTextArea.Value = num2str(value);
                case 'name4'
                    app.name4baseline = value;
                    app.baselinemlminTextArea.Value = num2str(value);
            end
            
        end

        % Callback function
        function baselinesetting00101EditFieldValueChanged(app, event)

        end

        % Button pushed function: changeButton_2
        function changeButton_2Pushed(app, event)
            bolus = app.bolusmlshotTextArea.Value;
            value = app.bolussetting0205EditField.Value;
            if value > 0.51
                app.bolusmlshotTextArea.Value = bolus;
            elseif value < 0.19
                app.bolusmlshotTextArea.Value = bolus;
            end
            switch app.name
                case 'name1'
                    app.name1bolus = value;
                    app.bolusmlshotTextArea.Value = num2str(value);
                case 'name2'
                    app.name2bolus = value;
                    app.bolusmlshotTextArea.Value = num2str(value);
                case 'name3'
                    app.name3bolus = value;
                    app.bolusmlshotTextArea.Value = num2str(value);
                case 'name4'
                    app.name4bolus = value;
                    app.bolusmlshotTextArea.Value = num2str(value);
            end
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            %left = app.medicineleftTextArea.Value;
            value = app.addtoEditField.Value;
            switch app.name
                case 'name1'
                    app.name1left = value;
                    app.medicineleftTextArea.Value = num2str(value);
                case 'name2'
                    app.name2left = value;
                    app.medicineleftTextArea.Value = num2str(value);
                case 'name3'
                    app.name3left = value;
                    app.medicineleftTextArea.Value = num2str(value);
                case 'name4'
                    app.name4left = value;
                    app.medicineleftTextArea.Value = num2str(value);
            end
        end

        % Callback function
        function nameDropDownValueChanged(app, event)

        end

        % Menu selected function: name1Menu
        function name1MenuSelected(app, event)
         app.baselinemlminTextArea.Value = num2str(app.name1baseline);
         app.bolusmlshotTextArea.Value = num2str(app.name1bolus);
         app.usedmldayTextArea.Value = num2str(app.name1used1);
         app.usedmlhrTextArea.Value = num2str(app.name1used2);
         app.medicineleftTextArea.Value = num2str(app.name1left);
         app.name = 'name1';
           
        end

        % Menu selected function: name2Menu
        function name2MenuSelected(app, event)
         app.baselinemlminTextArea.Value = num2str(app.name2baseline);
         app.bolusmlshotTextArea.Value = num2str(app.name2bolus);
         app.usedmldayTextArea.Value = num2str(app.name2used1);
         app.usedmlhrTextArea.Value = num2str(app.name2used2);
         app.medicineleftTextArea.Value = num2str(app.name2left);
         app.name = 'name2';
        end

        % Menu selected function: name3Menu
        function name3MenuSelected(app, event)
         app.baselinemlminTextArea.Value = num2str(app.name3baseline);
         app.bolusmlshotTextArea.Value = num2str(app.name3bolus);
         app.usedmldayTextArea.Value = num2str(app.name3used1);
         app.usedmlhrTextArea.Value = num2str(app.name3used2);
         app.medicineleftTextArea.Value = num2str(app.name3left);
         app.name = 'name3';
        end

        % Menu selected function: name4Menu
        function name4MenuSelected(app, event)
         app.baselinemlminTextArea.Value = num2str(app.name4baseline);
         app.bolusmlshotTextArea.Value = num2str(app.name4bolus);
         app.usedmldayTextArea.Value = num2str(app.name4used1);
         app.usedmlhrTextArea.Value = num2str(app.name4used2);
         app.medicineleftTextArea.Value = num2str(app.name4left);
         app.name = 'name4';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.name1Menu = uimenu(app.UIFigure);
            app.name1Menu.MenuSelectedFcn = createCallbackFcn(app, @name1MenuSelected, true);
            app.name1Menu.Text = 'name1';
            app.name2Menu = uimenu(app.UIFigure);
            app.name2Menu.MenuSelectedFcn = createCallbackFcn(app, @name2MenuSelected, true);
            app.name2Menu.Text = 'name2';
            app.name3Menu = uimenu(app.UIFigure);
            app.name3Menu.MenuSelectedFcn = createCallbackFcn(app, @name3MenuSelected, true);
            app.name3Menu.Text = 'name3';
            app.name4Menu = uimenu(app.UIFigure);
            app.name4Menu.MenuSelectedFcn = createCallbackFcn(app, @name4MenuSelected, true);
            app.name4Menu.Text = 'name4';
            app.limit3Label = uilabel(app.UIFigure);
            app.limit3Label.Text = '(limit: 3)';
            app.someotherinformationLabel = uilabel(app.UIFigure);
            app.someotherinformationLabel.Text = {'some other information'; '......'};
            app.usedmldayTextAreaLabel = uilabel(app.UIFigure);
            app.usedmldayTextAreaLabel.Text = 'used (ml/day)';
            app.usedmldayTextArea = uitextarea(app.UIFigure);
            app.usedmldayTextArea.Editable = 'off';
            app.usedmldayTextArea.Value = {'0.0'};
            app.limit1Label = uilabel(app.UIFigure);
            app.limit1Label.Text = '(limit: 1)';
            app.usedmlhrTextAreaLabel = uilabel(app.UIFigure);
            app.usedmlhrTextAreaLabel.Text = 'used (ml/hr)';
            app.usedmlhrTextArea = uitextarea(app.UIFigure);
            app.usedmlhrTextArea.Editable = 'off';
            app.usedmlhrTextArea.Value = {'0.0'};
            app.mlminLabel = uilabel(app.UIFigure);
            app.mlminLabel.Text = '(ml/min)';
            app.mlshotLabel = uilabel(app.UIFigure);
            app.mlshotLabel.Text = '(ml/shot)';
            app.mlLabel = uilabel(app.UIFigure);
            app.mlLabel.Text = '(ml)';
            app.baselinesetting00101EditFieldLabel = uilabel(app.UIFigure);
            app.baselinesetting00101EditFieldLabel.Text = 'baseline setting(0.01-0.1)';
            app.baselinesetting00101EditField = uieditfield(app.UIFigure, 'numeric');
            app.baselinesetting00101EditField.Value = 0.01;
            app.addtoEditFieldLabel = uilabel(app.UIFigure);
            app.addtoEditFieldLabel.Text = 'add to';
            app.addtoEditField = uieditfield(app.UIFigure, 'numeric');
            app.addtoEditField.Value = 20;
            app.changeButton = uibutton(app.UIFigure, 'push');
            app.changeButton.ButtonPushedFcn = createCallbackFcn(app, @changeButtonPushed, true);
            app.changeButton.Text = 'change';
            app.changeButton_2 = uibutton(app.UIFigure, 'push');
            app.changeButton_2.ButtonPushedFcn = createCallbackFcn(app, @changeButton_2Pushed, true);
            app.changeButton_2.Text = 'change';
            app.baselinemlminLabel = uilabel(app.UIFigure);
            app.baselinemlminLabel.Text = 'baseline(ml/min)';
            app.baselinemlminTextArea = uitextarea(app.UIFigure);
            app.baselinemlminTextArea.Editable = 'off';
            app.baselinemlminTextArea.Value = {'0.01'};
            app.bolusmlshotTextAreaLabel = uilabel(app.UIFigure);
            app.bolusmlshotTextAreaLabel.Text = 'bolus(ml/shot)';
            app.bolusmlshotTextArea = uitextarea(app.UIFigure);
            app.bolusmlshotTextArea.Editable = 'off';
            app.bolusmlshotTextArea.Value = {'0.2'};
            app.addButton = uibutton(app.UIFigure, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.medicineleftTextAreaLabel = uilabel(app.UIFigure);
            app.medicineleftTextAreaLabel.Text = 'medicine left:';
            app.medicineleftTextArea = uitextarea(app.UIFigure);
            app.medicineleftTextArea.Editable = 'off';
            app.medicineleftTextArea.Value = {'20'};
            app.mlLabel_2 = uilabel(app.UIFigure);
            app.mlLabel_2.Text = 'ml';
            app.bolussetting0205EditFieldLabel = uilabel(app.UIFigure);
            app.bolussetting0205EditFieldLabel.Text = 'bolus setting(0.2-0.5)';
            app.bolussetting0205EditField = uieditfield(app.UIFigure, 'numeric');
            app.bolussetting0205EditField.Value = 0.2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = physician

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end